classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button15      matlab.ui.control.Button
        Button14      matlab.ui.control.Button
        ButtonGroup4  matlab.ui.container.ButtonGroup
        Button_13     matlab.ui.control.RadioButton
        Button_12     matlab.ui.control.RadioButton
        ButtonGroup3  matlab.ui.container.ButtonGroup
        Button_11     matlab.ui.control.RadioButton
        Button_10     matlab.ui.control.RadioButton
        Button_9      matlab.ui.control.RadioButton
        Button_8      matlab.ui.control.RadioButton
        ButtonGroup2  matlab.ui.container.ButtonGroup
        Button_7      matlab.ui.control.RadioButton
        Button_6      matlab.ui.control.RadioButton
        Button_5      matlab.ui.control.RadioButton
        Button_4      matlab.ui.control.RadioButton
        ButtonGroup   matlab.ui.container.ButtonGroup
        Button_3      matlab.ui.control.RadioButton
        Button_2      matlab.ui.control.RadioButton
        Button2       matlab.ui.control.Button
        Button        matlab.ui.control.Button
        UIAxes6       matlab.ui.control.UIAxes
        UIAxes5       matlab.ui.control.UIAxes
        UIAxes4       matlab.ui.control.UIAxes
        UIAxes3       matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         x  % Description
        Fs % Description
        tmax
        v
        t
        T
        pxx
        y
        k
        h
        w
        N % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function start(app)
            app.Fs=250;
            app.T=1/app.Fs;
        end

        % Button pushed function: Button
        function read(app, event)
            f=uigetfile('*.txt');  
            app.x=load(f);
            app.N=length(app.x);
            app.T=1/app.Fs;
            app.tmax=app.N*app.T;
            app.t=0:app.T:app.tmax-app.T;
            plot(app.UIAxes,app.t,app.x)
        end

        % Button pushed function: Button2
        function FILTR(app, event)
          load("mat.mat");
          app.w=filter(Num,Den, app.x);
          plot(app.UIAxes2,app.t,app.w);
        end

        % Selection changed function: ButtonGroup2
        function ButtonGroup2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            if strcmpi(selectedButton.Text,'Юла-Уокера')
                p=pyulear(app.x,10)
                plot(app.UIAxes3,p);
            end
            if strcmpi(selectedButton.Text,'Берга')
                a=pburg(app.x,10)
                plot(app.UIAxes3,a);
            end 
            if strcmpi(selectedButton.Text,'Ковариационный')
                b=pcov(app.x,10)
                plot(app.UIAxes3,b);
            end
            if strcmpi(selectedButton.Text,'Модифицированный Ковариационный')
                c=pmcov(app.x,10)
                plot(app.UIAxes3,c);
            end 


        end

        % Selection changed function: ButtonGroup
        function mashtabdo(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
             if strcmpi(selectedButton.Text,'Логарифмический')
                set(app.UIAxes3,'Xlim', [0 100],'YScale', 'log')
            end
            if strcmpi(selectedButton.Text,'Линейный')
                set(app.UIAxes3, 'YScale', 'linear')
            end
        end

        % Selection changed function: ButtonGroup3
        function ButtonGroup3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup3.SelectedObject;          
            if strcmpi(selectedButton.Text,'Юла-Уокера')
                q=pyulear(app.w,10)
                plot(app.UIAxes4,q);
            end
            if strcmpi(selectedButton.Text,'Берга')
                u=pburg(app.w,10)
                plot(app.UIAxes4,u);
            end 
            if strcmpi(selectedButton.Text,'Ковариационный')
                e=pcov(app.w,10)
                plot(app.UIAxes4,e);
            end
            if strcmpi(selectedButton.Text,'Модифицированный Ковариационный')
                r=pmcov(app.w,10)
                plot(app.UIAxes4,r);
            end 
        end

        % Selection changed function: ButtonGroup4
        function ButtonGroup4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup4.SelectedObject;
            
             if strcmpi(selectedButton.Text,'Логарифмический')
                set(app.UIAxes4,'Xlim', [0 100],'YScale', 'log')
            end
            if strcmpi(selectedButton.Text,'Линейный')
                set(app.UIAxes4, 'YScale', 'linear')
            end
        end

        % Button pushed function: Button15
        function Button15Pushed(app, event)
            acf2=xcorr(app.w,'coeff');
            tacf2=-app.tmax+app.T:app.T:app.tmax-app.T;
            plot(app.UIAxes6,tacf2,acf2)
            set(app.UIAxes6, 'Xlim',[-2.5 2.5],'Ylim',[-1 1])
        end

        % Button pushed function: Button14
        function Button14Pushed(app, event)
            acf1=xcorr(app.x,'coeff');
            tacf1=-app.tmax+app.T:app.T:app.tmax-app.T;
            plot(app.UIAxes5,tacf1,acf1)
            set(app.UIAxes5, 'Xlim',[-2.5 2.5],'Ylim',[-1 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Исходный сигнал ЭЭГ')
            xlabel(app.UIAxes, 't,с')
            ylabel(app.UIAxes, 'ЭЭГ, мкВ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Сигнал ЭЭГ после фильтрации')
            xlabel(app.UIAxes2, 't,с')
            ylabel(app.UIAxes2, 'ЭЭГ, мкВ')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'СПМ сигнала до фильтрации')
            xlabel(app.UIAxes3, 'F,Гц')
            ylabel(app.UIAxes3, 'мкВ^2/Гц')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'СПМ сигнала после фильтрации')
            xlabel(app.UIAxes4, 'F,Гц')
            ylabel(app.UIAxes4, 'мкВ^2/Гц')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'АКФ исходного сигнала')
            xlabel(app.UIAxes5, 'dt, с')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'АКФ сигнала после фильтрации')
            xlabel(app.UIAxes6, 'dt, с')
            zlabel(app.UIAxes6, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @read, true);
            app.Button.Text = 'Чтение файла';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @FILTR, true);
            app.Button2.Text = 'Фильтрация';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @mashtabdo, true);
            app.ButtonGroup.Title = 'Масштаб:';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = 'Линейный';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = 'Логарифмический';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup2SelectionChanged, true);
            app.ButtonGroup2.Title = 'Метод спектрального оценивания:';
            app.Button_4 = uiradiobutton(app.ButtonGroup2);
            app.Button_4.Text = 'Юла-Уокера';
            app.Button_4.Value = true;
            app.Button_5 = uiradiobutton(app.ButtonGroup2);
            app.Button_5.Text = 'Берга';
            app.Button_6 = uiradiobutton(app.ButtonGroup2);
            app.Button_6.Text = 'Ковариационный';
            app.Button_7 = uiradiobutton(app.ButtonGroup2);
            app.Button_7.Text = 'Модифицированный ковариационный';
            app.ButtonGroup3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup3SelectionChanged, true);
            app.ButtonGroup3.Title = 'Метод спектрального оценивания:';
            app.Button_8 = uiradiobutton(app.ButtonGroup3);
            app.Button_8.Text = 'Юла-Уокера';
            app.Button_8.Value = true;
            app.Button_9 = uiradiobutton(app.ButtonGroup3);
            app.Button_9.Text = 'Берга';
            app.Button_10 = uiradiobutton(app.ButtonGroup3);
            app.Button_10.Text = 'Ковариационный';
            app.Button_11 = uiradiobutton(app.ButtonGroup3);
            app.Button_11.Text = 'Модифицированный ковариационный';
            app.ButtonGroup4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup4SelectionChanged, true);
            app.ButtonGroup4.Title = 'Масштаб:';
            app.Button_12 = uiradiobutton(app.ButtonGroup4);
            app.Button_12.Text = 'Линейный';
            app.Button_12.Value = true;
            app.Button_13 = uiradiobutton(app.ButtonGroup4);
            app.Button_13.Text = 'Логарифмический';
            app.Button14 = uibutton(app.UIFigure, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @Button14Pushed, true);
            app.Button14.Text = 'Вывод графика';
            app.Button15 = uibutton(app.UIFigure, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @Button15Pushed, true);
            app.Button15.Text = 'Вывод графика';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @start)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end